classdef ExamApp08 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveButton                  matlab.ui.control.Button
        ToFieldEditField            matlab.ui.control.EditField
        ToFieldLabel                matlab.ui.control.Label
        FromFieldEditField          matlab.ui.control.EditField
        FromFieldEditFieldLabel     matlab.ui.control.Label
        FilterTypeDropDown          matlab.ui.control.DropDown
        FilterTypeDropDownLabel     matlab.ui.control.Label
        FilterdSlider               matlab.ui.control.Slider
        FilterdSliderLabel          matlab.ui.control.Label
        RecNameLabel                matlab.ui.control.Label
        LoadDataButton              matlab.ui.control.Button
        RecordingnumberSlider       matlab.ui.control.Slider
        RecordingnumberSliderLabel  matlab.ui.control.Label
        SubjectSlider               matlab.ui.control.Slider
        SubjectSliderLabel          matlab.ui.control.Label
        FilterButton                matlab.ui.control.Button
        ChannelSlider               matlab.ui.control.Slider
        ChannelSliderLabel          matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG % Description
        FEEG
        FS
        TS
        recName
        recNum
        subject
        dataName
        Filter
    end
    
    methods (Access = private)
        function [T0, T1, T2] = getIntervals(app, recName,annotator,fs,sigLen)
            T0=[];
            T1=[];
            T2=[];
            
            [annot, ~, ~, ~, ~, cmt] = rdann(recName, annotator);
            cmt = string(cmt);
            for i=1:size(annot,1)
                splt=split(cmt(i), ' ');
                dur = str2double(splt(3))*fs;
                intEnd = annot(i) + dur-1;
                if (i<length(annot))
                    intEnd = min(annot(i+1)-1, intEnd);
                else
                    intEnd = min(sigLen, intEnd);
                end
                    
                if (splt(1)=="T0")
                    T0 = [T0; [annot(i) intEnd]];
                elseif (splt(1)=="T1")
                    T1 = [T1; annot(i) intEnd];
                elseif (splt(1)=="T2")
                    T2 = [T2; annot(i) intEnd];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.recName = 'eegmmidb/S001/S001R03.edf';
            [ eeg, fs, ts] = rdsamp(app.recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            app.FS = fs;
            app.TS = ts;
            
            plot(app.UIAxes, eeg(1, :))
            
            app.RecNameLabel.Text = strcat('Recording:', ' ', app.recName);
            app.dataName = 'S001R03';
        end

        % Value changing function: ChannelSlider
        function ChannelSliderValueChanging(app, event)
            changingValue = int32(event.Value);
            plot(app.UIAxes, app.EEG(changingValue, :))
            
            app.UIAxes.Title.String = strcat("EEG Signal Preview Channel ", int2str(changingValue));
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            [T0, T1, T2] = app.getIntervals(app.recName, 'event', app.FS, size(app.EEG, 2));
            
            if app.FilterTypeDropDown.Value == "Bandstop"
                tmp = bandstop(app.EEG, [str2num(app.FromFieldEditField.Value), str2num(app.ToFieldEditField.Value)], app.FS);
            else
                tmp = bandpass(app.EEG, [str2num(app.FromFieldEditField.Value), str2num(app.ToFieldEditField.Value)], app.FS);
            end
            
            app.FEEG = tmp;

            plot(app.UIAxes2, tmp(1, :))           
        end

        % Value changed function: RecordingnumberSlider
        function RecordingnumberSliderValueChanged(app, event)
            value = app.RecordingnumberSlider.Value;
            app.recNum = int32(value);
        end

        % Value changing function: RecordingnumberSlider
        function RecordingnumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.recNum = int32(changingValue);
        end

        % Value changing function: SubjectSlider
        function SubjectSliderValueChanging(app, event)
            changingValue = event.Value;
            app.subject = int32(changingValue);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            digits = numel(num2str(app.subject));
            
            nz = 3 - numel(num2str(app.subject));
            subName = 'S';

            for i = 1:nz
                subName = strcat(subName, '0');
            end
            subName = strcat(subName, int2str(app.subject));
            
            if numel(num2str(app.recNum)) < 2
                recN = strcat('0', int2str(app.recNum));
            else
                recN = int2str(app.recNum);
            end
            
            app.recName = strcat('eegmmidb/', subName, '/', strcat(subName, 'R', recN), '.edf');
            
            [ eeg, fs, ts] = rdsamp(app.recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            app.FS = fs;
            app.TS = ts;
            
            plot(app.UIAxes, eeg(1, :))
            
            app.RecNameLabel.Text = strcat('Recording:', ' ', app.recName);
            app.dataName = strcat(subName, 'R', recN);
        end

        % Value changing function: FilterdSlider
        function FilterdSliderValueChanging(app, event)
            changingValue = int32(event.Value);
            
            plot(app.UIAxes2, app.FEEG(changingValue, :))
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            hdr = edfheader("EDF+");
            hdr.NumDataRecords = 1;
            hdr.DataRecordDuration = seconds(length(app.EEG(:,1))/app.FS);
            hdr.NumSignals = 64;
            hdr.PhysicalMin = ones(64, 1) .* -8092;
            hdr.PhysicalMax = ones(64, 1) .* 8092;
            hdr.DigitalMin = ones(64, 1) .* -8092;
            hdr.DigitalMax = ones(64, 1) .* 8092;
            
            edfw = edfwrite(strcat(app.dataName, '_filtered.edf'), hdr, transpose(app.FEEG), 'InputSampleType',"physical");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG SIgnal Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered EEG Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ChannelSliderLabel = uilabel(app.UIFigure);
            app.ChannelSliderLabel.Text = 'Channel Slider';
            app.ChannelSlider = uislider(app.UIFigure);
            app.ChannelSlider.Limits = [1 64];
            app.ChannelSlider.ValueChangingFcn = createCallbackFcn(app, @ChannelSliderValueChanging, true);
            app.ChannelSlider.Value = 1;
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.SubjectSliderLabel = uilabel(app.UIFigure);
            app.SubjectSliderLabel.Text = 'Subject Slider';
            app.SubjectSlider = uislider(app.UIFigure);
            app.SubjectSlider.Limits = [1 109];
            app.SubjectSlider.ValueChangingFcn = createCallbackFcn(app, @SubjectSliderValueChanging, true);
            app.SubjectSlider.Value = 1;
            app.RecordingnumberSliderLabel = uilabel(app.UIFigure);
            app.RecordingnumberSliderLabel.Text = 'Recording number';
            app.RecordingnumberSlider = uislider(app.UIFigure);
            app.RecordingnumberSlider.Limits = [1 14];
            app.RecordingnumberSlider.ValueChangedFcn = createCallbackFcn(app, @RecordingnumberSliderValueChanged, true);
            app.RecordingnumberSlider.ValueChangingFcn = createCallbackFcn(app, @RecordingnumberSliderValueChanging, true);
            app.RecordingnumberSlider.Value = 1;
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RecNameLabel = uilabel(app.UIFigure);
            app.RecNameLabel.Text = 'RecNameLabel';
            app.FilterdSliderLabel = uilabel(app.UIFigure);
            app.FilterdSliderLabel.Text = 'Filterd Slider';
            app.FilterdSlider = uislider(app.UIFigure);
            app.FilterdSlider.Limits = [1 64];
            app.FilterdSlider.ValueChangingFcn = createCallbackFcn(app, @FilterdSliderValueChanging, true);
            app.FilterdSlider.Value = 1;
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Bandstop', 'Badnpass'};
            app.FilterTypeDropDown.Value = 'Bandstop';
            app.FromFieldEditFieldLabel = uilabel(app.UIFigure);
            app.FromFieldEditFieldLabel.Text = 'From Field';
            app.FromFieldEditField = uieditfield(app.UIFigure, 'text');
            app.FromFieldEditField.Placeholder = '0.5';
            app.FromFieldEditField.Value = '0.5';
            app.ToFieldLabel = uilabel(app.UIFigure);
            app.ToFieldLabel.Text = 'To Field ';
            app.ToFieldEditField = uieditfield(app.UIFigure, 'text');
            app.ToFieldEditField.Placeholder = '4';
            app.ToFieldEditField.Value = '4';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExamApp08

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end